classdef patientid__gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PATIENTIDUIFigure              matlab.ui.Figure
        EnterPATIENTIDandCONFIRMLabel  matlab.ui.control.Label
        PrefixnoteditableLabel         matlab.ui.control.Label
        ProgressiveuniqueIDLabel       matlab.ui.control.Label
        SuffixnoteditableLabel         matlab.ui.control.Label
        PATIENTLabel                   matlab.ui.control.Label
        VISITLabel                     matlab.ui.control.Label
        lprefix                        matlab.ui.control.Label
        ldot                           matlab.ui.control.Label
        lsuffix                        matlab.ui.control.Label
        INFO1                          matlab.ui.control.Label
        INFO2                          matlab.ui.control.Label
        HTML                           matlab.ui.control.HTML
        HTML2                          matlab.ui.control.HTML
        CONFIRMButton                  matlab.ui.control.Button
    end

    
    properties (Access = public)
        out
        temp
        error_00
        error_000
        report_path
        new_val_pat
        new_val_vis
        ID
        VISIT
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, in, a, b)
            % Change figure icon
            change__figicon(app.PATIENTIDUIFigure);
            
            PAT = upper(in.pathology);
            USER = in.buttons_on(strcmp(in.buttons_on(:,1), in.pathology), 3);
            USER = USER{1};
            CENTER = in.buttons_on(strcmp(in.buttons_on(:,1), in.pathology), 4);
            CENTER = CENTER{1};
            MOD = upper(in.mod);
            app.report_path = in.report_path;
            app.temp.max_id = in.max_id;
            app.temp.min_id = in.min_id;
            app.INFO1.Text = sprintf('INFO: Patient field requires a %d to %d digits or characters code',app.temp.min_id,app.temp.max_id);
            strprefix = strcat(CENTER, '-', PAT, '__P');
            strsuffix = MOD;
            
            set(app.lprefix, 'Text', strprefix);
            set(app.lsuffix, 'Text', strsuffix);
            set(app.HTML, 'Tooltip', sprintf('Please, enter between %d and %d -digit/chars (e.g. A01, 01B, 100).', in.min_id,in.max_id));
            set(app.HTML2, 'Tooltip', 'Please, enter only two-digit numbers (e.g. 01, 10, 99).');
            
            app.out.save = in.save;
            
            app.new_val_pat = 1;
            app.new_val_vis = 1;
            if ~isempty(a)
                app.HTML.Data = a;
                app.HTML2.Data = b;
            
                if length(a) >= in.min_id && length(a) <= in.max_id && length(b) == 2
                    if strcmpi(unique(a),'0') && strcmpi(unique(b),'0')
                        app.error_00 = 0;
                        app.error_000 = 0;
                    else
                        app.error_00 = 1;
                        app.error_000 = 1;
                    end
                else
                    app.error_00 = 0;
                    app.error_000 = 0;
                end
            else
                app.error_00 = 0;
                app.error_000 = 0;
            end
            
            
            movegui(app.PATIENTIDUIFigure,'center');  
        end

        % Callback function
        function IDValueChanged(app, event)
            try
                if isempty(app.ID.Value) || size(app.ID.Value, 2) < app.temp.min_id || size(app.ID.Value, 2) > app.temp.max_id ||...
                       sum(isstrprop(app.ID.Value,'wspace')) || sum(isstrprop(app.ID.Value,'punct'))
                    app.error_000 = 0;
                else
                    app.error_000 = 1;
                end    
            end
        end

        % Button pushed function: CONFIRMButton
        function CONFIRMButtonPushed(app, event)
            
            app.ID.Value = app.HTML.Data;
            app.VISIT.Value = app.HTML2.Data;
            if isempty(app.ID.Value) || size(app.ID.Value, 2) < app.temp.min_id || size(app.ID.Value, 2) > app.temp.max_id ||...
                       sum(isstrprop(app.ID.Value,'wspace')) || sum(isstrprop(app.ID.Value,'punct'))
                app.error_000 = 0;
                text = sprintf('Incorrect Patient code field. Please fill the field with between %d and %d alphanumeric chars.', app.temp.min_id, app.temp.max_id);
                uialert(app.PATIENTIDUIFigure, text,'Error','Icon','warning');
            else
                app.error_000 = 1;
            end

            if isempty(app.VISIT.Value) || size(app.VISIT.Value, 2) ~= 2 || isnan(str2double(app.VISIT.Value))
                app.error_00 = 0;
                text = sprintf('Incorrect Visit code fields.\nPlease fill the field with exactly 2 digits.');
                uialert(app.PATIENTIDUIFigure, text,'Error','Icon','warning');
            else
                app.error_00 = 1;
            end
            if strcmpi(unique(app.ID.Value),'0') && strcmpi(unique(app.VISIT.Value),'0')
                app.error_000 = 0;
                app.error_00 = 0;
                text = sprintf('Patient code and Visit code cannot contains only 0s.\nPlease, fill the fields with at least one other digit or char.');
                uialert(app.PATIENTIDUIFigure, text,'Error','Icon','warning');
            end
            if app.error_000 == 0 || app.error_00 == 0
%                 text = sprintf('Incorrect Patient (and/or Visit) code fields.\nPlease fill the fields with exactly 3 (2) digits.');
%                 uialert(app.PATIENTIDUIFigure, text,'Error','Icon','error');
            else
                ERROR = 0;
                if isempty(app.ID.Value) || isempty(app.VISIT.Value) || size(app.ID.Value, 2) < app.temp.min_id ...
                        || size(app.ID.Value, 2) > app.temp.max_id || sum(isstrprop(app.ID.Value,'wspace'))...
                        || sum(isstrprop(app.ID.Value,'punct')) || size(app.VISIT.Value, 2) ~= 2
                    app.ID.Value = '---';
                    app.VISIT.Value = '--';
                    ERROR = 1;
                end
                PID = strcat(app.lprefix.Text, app.ID.Value, '.', app.VISIT.Value, app.lsuffix.Text);
                lista = dir(fullfile(app.report_path, [PID(1:end-1),'*']));
                if ~isempty(lista)
                    lista2 = dir(fullfile(app.report_path, PID,[PID,'__report.pdf']));
                    if ~isempty(lista2)
                        new_file = [PID, '__report(', num2str(length(lista2)),').pdf'];
%                         path_old_file = fullfile(lista2(1).folder,lista2(1).name);
                        
                        msg = sprintf('Warning! This PATIENT ID has already been used.\n\nThe PDF report would be added to the existing folder %s\nand saved as\n%s.',PID,new_file);
                        title = 'ID already used';
                        selection = uiconfirm(app.PATIENTIDUIFigure,msg,title,...
                                   'Options',{'CONFIRM ID','CHANGE ID'},...
                                   'DefaultOption',1,'CancelOption',2,'Icon','warning');
                        if strcmp(selection,'Cancel') || strcmp(selection,'CHANGE ID')
                        else
                            save(app.out.save, 'PID', 'ERROR');
                            closereq
                        end
                    else
                        save(app.out.save, 'PID', 'ERROR');
                        closereq
                    end
                else
                    save(app.out.save, 'PID', 'ERROR');
                    closereq
                end
            end
        end

        % Callback function
        function VISITValueChanged(app, event)
            
            try
                if isempty(app.VISIT.Value) || size(app.VISIT.Value, 2) ~= 2 || isnan(str2double(app.VISIT.Value))
                    app.error_00 = 0;
                else
                    app.error_00 = 1;
                end
            end
        end

        % Data changed function: HTML
        function HTMLDataChanged(app, event)
%             data = app.HTML.Data;
            app.ID.Value = app.HTML.Data;
            try
                if isempty(app.HTML.Data) || size(app.HTML.Data, 2) < app.temp.min_id || size(app.HTML.Data, 2) > app.temp.max_id ||...
                       sum(isstrprop(app.HTML.Data,'wspace')) || sum(isstrprop(app.HTML.Data,'punct'))
                    app.error_000 = 0;
                else
                    app.error_000 = 1;
                end
            end
        end

        % Data changed function: HTML2
        function HTML2DataChanged(app, event)
%             data = app.HTML2.Data;
            
            app.VISIT.Value = app.HTML2.Data;
            try
                if isempty(app.HTML2.Data) || size(app.HTML2.Data, 2) ~= 2 || isnan(str2double(app.HTML2.Data))
                    app.error_00 = 0;
                else
                    app.error_00 = 1;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PATIENTIDUIFigure = uifigure('Visible', 'off');
            app.PATIENTIDUIFigure.Name = 'PATIENT ID';
            app.EnterPATIENTIDandCONFIRMLabel = uilabel(app.PATIENTIDUIFigure);
            app.EnterPATIENTIDandCONFIRMLabel.Text = 'Enter PATIENT ID and CONFIRM';
            app.PrefixnoteditableLabel = uilabel(app.PATIENTIDUIFigure);
            app.PrefixnoteditableLabel.Text = {'Prefix'; '(not editable)'};
            app.ProgressiveuniqueIDLabel = uilabel(app.PATIENTIDUIFigure);
            app.ProgressiveuniqueIDLabel.Text = {'Progressive'; 'unique ID'};
            app.SuffixnoteditableLabel = uilabel(app.PATIENTIDUIFigure);
            app.SuffixnoteditableLabel.Text = {'Suffix'; '(not editable)'};
            app.PATIENTLabel = uilabel(app.PATIENTIDUIFigure);
            app.PATIENTLabel.Enable = 'off';
            app.PATIENTLabel.Text = 'PATIENT';
            app.VISITLabel = uilabel(app.PATIENTIDUIFigure);
            app.VISITLabel.Enable = 'off';
            app.VISITLabel.Text = 'VISIT';
            app.lprefix = uilabel(app.PATIENTIDUIFigure);
            app.lprefix.Text = '-';
            app.ldot = uilabel(app.PATIENTIDUIFigure);
            app.ldot.Text = '.';
            app.lsuffix = uilabel(app.PATIENTIDUIFigure);
            app.lsuffix.Text = '-';
            app.INFO1 = uilabel(app.PATIENTIDUIFigure);
            app.INFO1.Text = 'INFO: Patient field requires a 3 to 7 digits or characters code';
            app.INFO2 = uilabel(app.PATIENTIDUIFigure);
            app.INFO2.Text = 'Visit field requires a 2 digits code';
            app.HTML = uihtml(app.PATIENTIDUIFigure);
            app.HTML.HTMLSource = 'place_holder_patient.html';
            app.HTML.DataChangedFcn = createCallbackFcn(app, @HTMLDataChanged, true);
            app.HTML2 = uihtml(app.PATIENTIDUIFigure);
            app.HTML2.HTMLSource = 'place_holder_visit.html';
            app.HTML2.DataChangedFcn = createCallbackFcn(app, @HTML2DataChanged, true);
            app.CONFIRMButton = uibutton(app.PATIENTIDUIFigure, 'push');
            app.CONFIRMButton.ButtonPushedFcn = createCallbackFcn(app, @CONFIRMButtonPushed, true);
            app.CONFIRMButton.Text = 'CONFIRM';
            % Show the figure after all components are created
            app.PATIENTIDUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = patientid__gui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PATIENTIDUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PATIENTIDUIFigure)
        end
    end
end