classdef trace4__adduser_supuser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TRACE4BUSUIFigure              matlab.ui.Figure
        USERNAMEEditFieldLabel         matlab.ui.control.Label
        USERNAMEEditField              matlab.ui.control.EditField
        PASSWORDEditFieldLabel         matlab.ui.control.Label
        PASSWORDEditField              matlab.ui.control.EditField
        Image                          matlab.ui.control.Image
        AdduserButton                  matlab.ui.control.Button
        NEWUSERLabel                   matlab.ui.control.Label
        error__message                 matlab.ui.control.Label
        errormex                       matlab.ui.control.Label
        ADMINLabel                     matlab.ui.control.Label
        label                          matlab.ui.control.Label
        USERNAMEEditField_2Label       matlab.ui.control.Label
        USERNAMEEditField_2            matlab.ui.control.EditField
        PASSWORDEditField_2Label       matlab.ui.control.Label
        PASSWORDEditField_2            matlab.ui.control.EditField
        CONFIRMPASSWORDEditFieldLabel  matlab.ui.control.Label
        CONFIRMPASSWORDEditField       matlab.ui.control.EditField
    end

    
    properties (Access = private)
        username
        password
        license__fullpath
        check
        newpwd
        confirm__newpwd
        newusr
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, supusern, suppwd)
            % Set main buttons / functions invisible
            change__figicon(app.TRACE4BUSUIFigure);
            label_copy = string__copyright;
            set(app.label,'text',label_copy);
            gui__handles{1} = app.USERNAMEEditField_2;
            gui__handles{2} = app.USERNAMEEditField_2Label;
%             gui__handles{3} = app.PASSWORDEditField;
%             gui__handles{4} = app.PASSWORDEditFieldLabel;
            gui__handles{3} = app.PASSWORDEditField_2;
            gui__handles{4} = app.PASSWORDEditField_2Label;
            gui__handles{5} = app.CONFIRMPASSWORDEditField;
%             gui__handles{6} = app.CONFIRMPASSWORDLabel;
            app.username = supusern;
            app.password = suppwd;
            for i = 1:size(gui__handles,2)
                set(gui__handles{i},'Visible','off');
            end
            
            movegui(app.TRACE4BUSUIFigure,'center');
            
            [cl, app.license__fullpath] = check__license();
            if cl == 1
                for i = 1:size(gui__handles,2)
                    set(gui__handles{i},'Visible','on');
                end
            end     
            
            app.username = app.USERNAMEEditField.Value;
        end

        % Button pushed function: AdduserButton
        function AdduserButtonPushed(app, event)
            set(app.error__message,'Visible','off');
            set(app.errormex,'Visible','off');
            app.check = add__user(app.username, app.password, app.license__fullpath, app.newusr, app.newpwd, app.confirm__newpwd);
            % check =  1  Success
            % check =  0  Username or password incorrect
            % check = -1  New password mismatch
            % check = -2  User already exists
            if app.check == 1
                closereq;
                trace4BUS__superuser;
            elseif app.check == 0
                set(app.error__message,'Visible','on');
            elseif app.check == -1  
                set(app.errormex,'Text','New password mismatch','Visible','on');
            elseif app.check == -2
                set(app.errormex,'Text','User already exists','Visible','on');
            end
        end

        % Value changed function: PASSWORDEditField_2
        function PASSWORDEditField_2ValueChanged(app, event)
            app.newpwd = app.PASSWORDEditField_2.Value;
        end

        % Value changed function: CONFIRMPASSWORDEditField
        function CONFIRMPASSWORDEditFieldValueChanged(app, event)
            app.confirm__newpwd = app.CONFIRMPASSWORDEditField.Value;
        end

        % Value changed function: USERNAMEEditField_2
        function USERNAMEEditField_2ValueChanged(app, event)
            app.newusr = app.USERNAMEEditField_2.Value;
        end

        % Key press function: TRACE4BUSUIFigure
        function TRACE4BUSUIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key,'return')
                app.AdduserButtonPushed();
            end
        end

        % Window key press function: TRACE4BUSUIFigure
        function TRACE4BUSUIFigureWindowKeyPress(app, event)
            key = event.Key;
            if strcmp(key,'return')
                app.AdduserButtonPushed();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TRACE4BUSUIFigure = uifigure('Visible', 'off');
            app.TRACE4BUSUIFigure.Name = 'TRACE4BUS';
            app.TRACE4BUSUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @TRACE4BUSUIFigureWindowKeyPress, true);
            app.TRACE4BUSUIFigure.KeyPressFcn = createCallbackFcn(app, @TRACE4BUSUIFigureKeyPress, true);
            app.USERNAMEEditFieldLabel = uilabel(app.TRACE4BUSUIFigure);
            app.USERNAMEEditFieldLabel.Enable = 'off';
            app.USERNAMEEditFieldLabel.Visible = 'off';
            app.USERNAMEEditFieldLabel.Text = 'USERNAME';
            app.USERNAMEEditField = uieditfield(app.TRACE4BUSUIFigure, 'text');
            app.USERNAMEEditField.Editable = 'off';
            app.USERNAMEEditField.Enable = 'off';
            app.USERNAMEEditField.Visible = 'off';
            app.USERNAMEEditField.Value = 'supadmin';
            app.PASSWORDEditFieldLabel = uilabel(app.TRACE4BUSUIFigure);
            app.PASSWORDEditFieldLabel.Enable = 'off';
            app.PASSWORDEditFieldLabel.Visible = 'off';
            app.PASSWORDEditFieldLabel.Text = 'PASSWORD';
            app.PASSWORDEditField = uieditfield(app.TRACE4BUSUIFigure, 'text');
            app.PASSWORDEditField.Enable = 'off';
            app.PASSWORDEditField.Visible = 'off';
            app.Image = uiimage(app.TRACE4BUSUIFigure);
            app.Image.ImageSource = 'logo-trace4bus.png';
            app.AdduserButton = uibutton(app.TRACE4BUSUIFigure, 'push');
            app.AdduserButton.ButtonPushedFcn = createCallbackFcn(app, @AdduserButtonPushed, true);
            app.AdduserButton.Text = 'Add user';
            app.NEWUSERLabel = uilabel(app.TRACE4BUSUIFigure);
            app.NEWUSERLabel.Text = 'NEW USER';
            app.error__message = uilabel(app.TRACE4BUSUIFigure);
            app.error__message.Visible = 'off';
            app.error__message.Text = 'Username or password incorrect';
            app.errormex = uilabel(app.TRACE4BUSUIFigure);
            app.errormex.Visible = 'off';
            app.errormex.Text = 'New password mismatch';
            app.ADMINLabel = uilabel(app.TRACE4BUSUIFigure);
            app.ADMINLabel.Enable = 'off';
            app.ADMINLabel.Visible = 'off';
            app.ADMINLabel.Text = 'ADMIN';
            app.label = uilabel(app.TRACE4BUSUIFigure);
            app.label.Text = '© 2020 DeepTrace Technologies S.R.L.';
            app.USERNAMEEditField_2Label = uilabel(app.TRACE4BUSUIFigure);
            app.USERNAMEEditField_2Label.Text = 'USERNAME';
            app.USERNAMEEditField_2 = uieditfield(app.TRACE4BUSUIFigure, 'text');
            app.USERNAMEEditField_2.ValueChangedFcn = createCallbackFcn(app, @USERNAMEEditField_2ValueChanged, true);
            app.PASSWORDEditField_2Label = uilabel(app.TRACE4BUSUIFigure);
            app.PASSWORDEditField_2Label.Text = 'PASSWORD';
            app.PASSWORDEditField_2 = uieditfield(app.TRACE4BUSUIFigure, 'text');
            app.PASSWORDEditField_2.ValueChangedFcn = createCallbackFcn(app, @PASSWORDEditField_2ValueChanged, true);
            app.CONFIRMPASSWORDEditFieldLabel = uilabel(app.TRACE4BUSUIFigure);
            app.CONFIRMPASSWORDEditFieldLabel.Text = {'CONFIRM'; 'PASSWORD'};
            app.CONFIRMPASSWORDEditField = uieditfield(app.TRACE4BUSUIFigure, 'text');
            app.CONFIRMPASSWORDEditField.ValueChangedFcn = createCallbackFcn(app, @CONFIRMPASSWORDEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.TRACE4BUSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trace4__adduser_supuser(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TRACE4BUSUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TRACE4BUSUIFigure)
        end
    end
end