classdef trace4bus__first_time < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        DocumentationMenu            matlab.ui.container.Menu
        UserManualMenu               matlab.ui.container.Menu
        TechnicalSheetMenu           matlab.ui.container.Menu
        UltrasoundImageProtocolMenu  matlab.ui.container.Menu
        SegmentationProcedureMenu    matlab.ui.container.Menu
        Menu                         matlab.ui.container.Menu
        LogoutMenu                   matlab.ui.container.Menu
        text__general                matlab.ui.control.Label
        logo                         matlab.ui.control.Image
        DeepTraceTechnologiesSRL2020Label  matlab.ui.control.Label
        indications                  matlab.ui.control.Button
        terms                        matlab.ui.control.Button
        regulatory                   matlab.ui.control.Button
        subtitle                     matlab.ui.control.Label
        testoLabel                   matlab.ui.control.Label
        IreadtherequireddocumentationCheckBox  matlab.ui.control.CheckBox
        DontshowthismessageagainCheckBox_2  matlab.ui.control.CheckBox
        CONTINUEButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        output
        lpath

    end
    
    properties (Access = public)
    end
    
    methods (Access = private)        

    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function trace4bus__first_time_OpeningFcn(app, in, lpath)
            
            % Change figure icon
            change__figicon(app.figure1);
            
            label_copy = string__copyright;
            set(app.DeepTraceTechnologiesSRL2020Label,'text',label_copy);
            app.output = in;
            if strcmp(in.pathology, 'bc')
                set(app.logo,'ImageSource','logo-trace4bus(c).png');
                set(app.figure1, 'Name', 'TRACE4BUS');    
            end
            app.lpath = lpath;

            
            movegui(app.figure1,'center');  


            % uialert(app.figure1, 'The files (MRI scan and PDF containing the results of the neuropsychological tests) used for the analysis will be anonymized and their filename will be modified according to the Patient ID. Please, store any relevant information or be sure to have a copy.', 'Warning','Icon','warning')
            
        end

        % Button pushed function: indications
        function indicationsButtonPushed(app, event)
            uialert(app.figure1, sprintf('Trace4BUS is the software of the Trace4 family indicated for subjects at risk of breast cancer on the recommendation of breast specialists. Physicians with experience in breast ultrasound can use Trace4BUS to support the reporting of ultrasound-detected suspicious breast mass investigations, to classify the probability of malignancy of such masses and to classify the diagnostic category of these masses according to the BI-RADS classification. Breast specialists can use Trace4BUS to help diagnose and manage the patient following ultrasound evaluation with Trace4BUS.\n\nThe TraceBUS software provides the risk of malignancy of the suspicious breast mass at the date of the ultrasound examination in which it was detected, with ultrasound images processed by Trace4BUS.'), 'Indications of use','Icon','info');
        end

        % Button pushed function: terms
        function termsButtonPushed(app, event)
            uialert(app.figure1, sprintf("1.	Acceptance of Agreement. You agree to the terms and conditions outlined in this Terms of Use Agreement ('Agreement') with respect to the software 'TRACE4BUS' created by DeepTrace Technologies S.R.L. (the 'software').\n2.	Intellectual Property/Limited License to Users. Any unauthorized use of the Materials, Content or Tools within this Software may violate laws and the Terms of Use. You agree not to copy, republish, frame, download, transmit, modify, rent, lease, loan, sell, assign, distribute, license, sublicense, reverse engineer, or create derivative works and tools based on this Software, its Materials, Content or Tools or their selection and arrangement, except as expressly authorized herein by DeepTrace Technologies S.R.L. You agree that you will not decompile, reverse engineer, or otherwise attempt to discover the source code of this software.\n3.	Limited Right to Use. The viewing, using, printing or downloading of any Material, Content, Graphic, Form or Document from this software grants you only a limited, nonexclusive license for use solely by the purpose defined under the Contract with by DeepTrace Technologies S.R.L, and not for republication, distribution, assignment, sublicense, sale, preparation of derivative works or other use unauthorized by DeepTrace Technologies S.R.L. No part of any content, form or document may be reproduced in any form or incorporated into any information retrieval system, electronic or mechanical, other than for the use defined under the Contract (but not for resale or redistribution). The rights granted to you constitute a license and not a transfer of title."), 'Terms of use','Icon','info');
        end

        % Button pushed function: regulatory
        function regulatoryButtonPushed(app, event)
            Regulatory();
%             uialert(app.figure1, "This software is not a medical device. It is not CE marked nor FDA cleared. Any use of this software and the associated information is for research and statistical analysis only.", 'Regulatory','Icon','info');
        end

        % Menu selected function: TechnicalSheetMenu
        function TechnicalSheetMenuSelected(app, event)
%             if isdeployed
%                 
%                 p = mfilename('fullpath');
%                 endout = regexp(p, filesep, 'split');
%             
%                 for i = 1:size(endout,2)-1
%                     if i == 1
%                         path_exe = endout{1,1};
%                     else
%                         path_exe = strcat(path_exe, '/' ,endout{1,i});
%                     end  
%                 end
%                 
%                 path_pdf = fullfile(path_exe, 'docs', 'TRACE4BUS__Technical_Sheet_en-it.pdf');
%                 system(['start "TRACE4BUS | Technical Sheet" ','"', path_pdf,'"']);
%                 
%             else
%                 
%                 system(['start "TRACE4BUS | Technical Sheet" ','"', 'docs/TRACE4BUS__Technical_Sheet_en-it.pdf','"']);
%                 
%             end

        end

        % Menu selected function: UserManualMenu
        function UserManualMenuSelected(app, event)
%             if isdeployed
%                 
%                 p = mfilename('fullpath');
%                 endout = regexp(p, filesep, 'split');
%             
%                 for i = 1:size(endout,2)-1
%                     if i == 1
%                         path_exe = endout{1,1};
%                     else
%                         path_exe = strcat(path_exe, '/' ,endout{1,i});
%                     end  
%                 end
%                 
%                 path_pdf = fullfile(path_exe, 'docs', 'TRACE4BUS__PR_User_Manual_V2.0_en-it.pdf');
%                 system(['start "TRACE4BUS | Technical Sheet" ','"', path_pdf,'"']);
%                 
%             else
%                 
%                 system(['start "TRACE4BUS | User Manual" ','"', 'docs/TRACE4BUS__PR_User_Manual_V2.0_en-it.pdf','"']);
%                 
%             end

        end

        % Menu selected function: LogoutMenu
        function LogoutMenuSelected(app, event)
            out = app.output.modelli;
            closereq;
            trace4BUS_login(out);
        end

        % Menu selected function: UltrasoundImageProtocolMenu
        function UltrasoundImageProtocolMenuSelected(app, event)
%             if isdeployed
%                 
%                 p = mfilename('fullpath');
%                 endout = regexp(p, filesep, 'split');
%             
%                 for i = 1:size(endout,2)-1
%                     if i == 1
%                         path_exe = endout{1,1};
%                     else
%                         path_exe = strcat(path_exe, '/' ,endout{1,i});
%                     end  
%                 end
%                 
%                 path_pdf = fullfile(path_exe, 'docs', 'TRACE4BUS__USI_Acquisition_Protocol_en-it.pdf');
%                 system(['start "TRACE4BUS | USI Protocol" ','"', path_pdf,'"']);
%                 
%             else
%                 
%                 system(['start "TRACE4BUS | USI Protocol" ','"', 'docs/TRACE4BUS__USI_Acquisition_Protocol_en-it.pdf','"']);
%                 
%             end
        end

        % Value changed function: 
        % IreadtherequireddocumentationCheckBox
        function IreadtherequireddocumentationCheckBoxValueChanged(app, event)
            if app.IreadtherequireddocumentationCheckBox.Value
                app.CONTINUEButton.Enable = 'on';
            else
                app.CONTINUEButton.Enable = 'off';
            end
            
        end

        % Value changed function: DontshowthismessageagainCheckBox_2
        function DontshowthismessageagainCheckBox_2ValueChanged(app, event)
%             value = app.DontshowthismessageagainCheckBox_2.Value;
            
        end

        % Menu selected function: SegmentationProcedureMenu
        function SegmentationProcedureMenuSelected(app, event)
            
        end

        % Button pushed function: CONTINUEButton
        function CONTINUEButtonPushed(app, event)
            if app.DontshowthismessageagainCheckBox_2.Value
                license = load(app.lpath.local);
                usr__ = crypting(app.output.username, 1, 0);
                index = find(cellfun(@(s) ~isempty(strfind(usr__,s)), license.auth_use.login(1,:)));
                license.auth_use.login{4,index} = 0;
                auth_use = license.auth_use;
                save(app.lpath.local,'auth_use','-append');
            end
            out = app.output;
            closereq;
            trace4bus__multi_test1(out,[],[]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'TRACE4BUS';
            app.DocumentationMenu = uimenu(app.figure1);
            app.DocumentationMenu.Text = '  Documentation  ';
            app.UserManualMenu = uimenu(app.DocumentationMenu);
            app.UserManualMenu.MenuSelectedFcn = createCallbackFcn(app, @UserManualMenuSelected, true);
            app.UserManualMenu.Text = 'User Manual';
            app.TechnicalSheetMenu = uimenu(app.DocumentationMenu);
            app.TechnicalSheetMenu.MenuSelectedFcn = createCallbackFcn(app, @TechnicalSheetMenuSelected, true);
            app.TechnicalSheetMenu.Text = 'Technical Sheet';
            app.UltrasoundImageProtocolMenu = uimenu(app.DocumentationMenu);
            app.UltrasoundImageProtocolMenu.MenuSelectedFcn = createCallbackFcn(app, @UltrasoundImageProtocolMenuSelected, true);
            app.UltrasoundImageProtocolMenu.Text = 'Ultrasound Image Protocol';
            app.SegmentationProcedureMenu = uimenu(app.DocumentationMenu);
            app.SegmentationProcedureMenu.MenuSelectedFcn = createCallbackFcn(app, @SegmentationProcedureMenuSelected, true);
            app.SegmentationProcedureMenu.Text = 'Segmentation Procedure';
            app.Menu = uimenu(app.figure1);
            app.Menu.Enable = 'off';
            app.Menu.Text = '                                                                                                                                                                                                                                                                                                            ';
            app.LogoutMenu = uimenu(app.figure1);
            app.LogoutMenu.MenuSelectedFcn = createCallbackFcn(app, @LogoutMenuSelected, true);
            app.LogoutMenu.Text = '  Logout  ';
            app.text__general = uilabel(app.figure1);
            app.text__general.Text = 'Predict the risk of Breast Cancer';
            app.logo = uiimage(app.figure1);
            app.logo.ImageSource = 'logo-trace4bus(c).png';
            app.DeepTraceTechnologiesSRL2020Label = uilabel(app.figure1);
            app.DeepTraceTechnologiesSRL2020Label.Text = '© DeepTrace Technologies SRL | 2020';
            app.indications = uibutton(app.figure1, 'push');
            app.indications.ButtonPushedFcn = createCallbackFcn(app, @indicationsButtonPushed, true);
            app.indications.Tooltip = {''};
            app.indications.Text = 'INDICATIONS OF USE';
            app.terms = uibutton(app.figure1, 'push');
            app.terms.ButtonPushedFcn = createCallbackFcn(app, @termsButtonPushed, true);
            app.terms.Tooltip = {''};
            app.terms.Text = 'TERMS OF USE';
            app.regulatory = uibutton(app.figure1, 'push');
            app.regulatory.ButtonPushedFcn = createCallbackFcn(app, @regulatoryButtonPushed, true);
            app.regulatory.Tooltip = {''};
            app.regulatory.Text = 'REGULATORY';
            app.subtitle = uilabel(app.figure1);
            app.subtitle.Text = 'BREAST MASS ANALYZER';
            app.testoLabel = uilabel(app.figure1);
            app.testoLabel.Text = {'TRACE4BUS is the software of the TRACE4 family indicated for subjects at risk of breast cancer on the recommendation of breast specialists.'; 'Physicians with experience in breast ultrasound can use TRACE4BUS to support the reporting of ultrasound-detected suspicious breast'; 'mass investigations, to classify the probability of malignancy of such masses and to classify the diagnostic category of these masses'; 'according to the BI-RADS classification. Breast specialists can use TRACE4BUS to help diagnose and manage the patient following'; 'ultrasound evaluation with TRACE4BUS.'; 'The TRACEBUS software provides the risk of malignancy of the suspicious breast mass at the date of the ultrasound examination in which'; 'it was detected, with ultrasound images processed by TRACE4BUS.'; ''; 'TRACE4BUS is a Class IIa Medical Device pursuant to MDR 745/2017 rule 11.'; ''; 'This software is not aimed at the general public and only breast imaging specialists can use this software.'; ''; 'User – software interaction is required (e.g. breast mass accurate segmentation).'; ''; 'The AI model inside TRACE4BUS is developed only for suspicious breast masses (>0% likelihood of malignancy) at 2D ultrasound image.'; ''; 'AI model training and testing data were acquired from European Caucasian subjects.'; ''; 'The reading of the user manual is mandatory to the use of TRACE4BUS.'; 'The electronic instructions on use are available for consultation in the “Documentation” menu bar.'};
            app.IreadtherequireddocumentationCheckBox = uicheckbox(app.figure1);
            app.IreadtherequireddocumentationCheckBox.ValueChangedFcn = createCallbackFcn(app, @IreadtherequireddocumentationCheckBoxValueChanged, true);
            app.IreadtherequireddocumentationCheckBox.Text = 'I read the required documentation';
            app.DontshowthismessageagainCheckBox_2 = uicheckbox(app.figure1);
            app.DontshowthismessageagainCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @DontshowthismessageagainCheckBox_2ValueChanged, true);
            app.DontshowthismessageagainCheckBox_2.Text = 'Don''t show this message again';
            app.CONTINUEButton = uibutton(app.figure1, 'push');
            app.CONTINUEButton.ButtonPushedFcn = createCallbackFcn(app, @CONTINUEButtonPushed, true);
            app.CONTINUEButton.Enable = 'off';
            app.CONTINUEButton.Text = 'CONTINUE';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trace4bus__first_time(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)trace4bus__first_time_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end